classdef decryption < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DecryptionUIFigure           matlab.ui.Figure
        DecryptedDataEditField       matlab.ui.control.EditField
        DecryptedDataEditFieldLabel  matlab.ui.control.Label
        DecryptButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DecryptButton
        function DecryptButtonPushed(app, event)
            [file,path] = uigetfile('*.mat','File Selector');
            cipher = fullfile(path, file);
            
            lastwarn('', '');
            load(cipher,"k","c");
            [warnMsg, warnId] = lastwarn();
            if(isempty(warnId))
                key = '';
                for i = 1 : size(k, 1)
                    for j = 1 : size(k, 2)
                        key = [key sprintf('%d',k(i,j))];
                        key = [key ' '];
                    end
                    key = [key newline];
                end

                msgbox(sprintf('Cipher: %s%cKey:%c%s', c, newline, newline, key),'Success', 'help')
                
                p = decrypt(c, k);
                app.DecryptedDataEditField.Value = p;
            else
                msgbox(warnMsg,"Error","error");
            end

        end

        % Close request function: DecryptionUIFigure
        function DecryptionUIFigureCloseRequest(app, event)
            main;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DecryptionUIFigure = uifigure('Visible', 'off');
            app.DecryptionUIFigure.Name = 'Decryption';
            app.DecryptionUIFigure.CloseRequestFcn = createCallbackFcn(app, @DecryptionUIFigureCloseRequest, true);
            app.DecryptButton = uibutton(app.DecryptionUIFigure, 'push');
            app.DecryptButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptButtonPushed, true);
            app.DecryptButton.Text = 'Decrypt';
            app.DecryptedDataEditFieldLabel = uilabel(app.DecryptionUIFigure);
            app.DecryptedDataEditFieldLabel.Text = 'Decrypted Data';
            app.DecryptedDataEditField = uieditfield(app.DecryptionUIFigure, 'text');
            app.DecryptedDataEditField.Editable = 'off';
            % Show the figure after all components are created
            app.DecryptionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = decryption

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DecryptionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DecryptionUIFigure)
        end
    end
end